classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ShoeImageHSVButton      matlab.ui.control.Button
        ShowImageGRAYButton     matlab.ui.control.Button
        NextButton              matlab.ui.control.Button
        ReadShowImageRGBButton  matlab.ui.control.Button
        UIAxes_3                matlab.ui.control.UIAxes
        UIAxes_2                matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            run('app2.mlapp');
        end

        % Button pushed function: ReadShowImageRGBButton
        function ReadShowImageRGBButtonPushed(app, event)
            x=imread('blue.png');
            imshow(x,'Parent',app.UIAxes);
        end

        % Button pushed function: ShowImageGRAYButton
        function ShowImageGRAYButtonPushed(app, event)
            x=imread('blue.png');
            y=rgb2gray(x);
            imshow(y,'Parent',app.UIAxes_2);
        end

        % Button pushed function: ShoeImageHSVButton
        function ShoeImageHSVButtonPushed(app, event)
            x=imread('blue.png');
            y=rgb2hsv(x);
            imshow(y,'Parent',app.UIAxes_3);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.ReadShowImageRGBButton = uibutton(app.UIFigure, 'push');
            app.ReadShowImageRGBButton.ButtonPushedFcn = createCallbackFcn(app, @ReadShowImageRGBButtonPushed, true);
            app.ReadShowImageRGBButton.Text = 'Read & Show Image RGB';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.ShowImageGRAYButton = uibutton(app.UIFigure, 'push');
            app.ShowImageGRAYButton.ButtonPushedFcn = createCallbackFcn(app, @ShowImageGRAYButtonPushed, true);
            app.ShowImageGRAYButton.Text = 'Show Image GRAY';
            app.ShoeImageHSVButton = uibutton(app.UIFigure, 'push');
            app.ShoeImageHSVButton.ButtonPushedFcn = createCallbackFcn(app, @ShoeImageHSVButtonPushed, true);
            app.ShoeImageHSVButton.Text = 'Shoe Image HSV';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end