classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        ShowProjectButton  matlab.ui.control.Button
        BackButton         matlab.ui.control.Button
        NextButton         matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            run('app4.mlapp');
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            run('app2.mlapp');
        end

        % Button pushed function: ShowProjectButton
        function ShowProjectButtonPushed(app, event)
            x=imread('Hello.png');
            for i=1:1:1536 
                for j=1:1:2732 
                    if j>=1 && j<= 342 || j>=683 && j<= 1024 || j>=1365 && j<= 1706 || j>=2047 && j<= 2388  
                        if i>=1 && i<=192 || i>=384 && i<=576 || i>=768 && i<=960 || i>=1152 && i<=1344 
                            if x (i ,j)==0
                                 x(i , j , 3)=255;
                                 x(i , j , 2)=255;
                                 x(i , j , 1)=255;
                            end
                        end
                    end
                end
            end
            for i=1:1:1536
                for j=1:1:2732 
                    if i>=193 && i<= 385 || i>=577 && i<= 769 || i>=961 && i<=1153 || i>=1345 && i<=1537
                        if j>=342 && j<= 683 || j>=1024 && j<= 1365 || j>=1706 && j<= 2047 || j>=2388 && j<= 2732
                            if x (i ,j)==0
                                 x(i , j ,  3)=255;
                                 x(i , j , 2)=255;
                                 x(i , j , 1)=255;
                           end
                        end
                    end
                end
            end
            imshow(x,'Parent',app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.ShowProjectButton = uibutton(app.UIFigure, 'push');
            app.ShowProjectButton.ButtonPushedFcn = createCallbackFcn(app, @ShowProjectButtonPushed, true);
            app.ShowProjectButton.Text = 'Show Project';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end