classdef app5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        DisplayButton  matlab.ui.control.Button
        BackButton     matlab.ui.control.Button
        UIAxes_6       matlab.ui.control.UIAxes
        UIAxes_5       matlab.ui.control.UIAxes
        UIAxes_4       matlab.ui.control.UIAxes
        UIAxes_3       matlab.ui.control.UIAxes
        UIAxes_2       matlab.ui.control.UIAxes
        UIAxes         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            run('app4.mlapp');
        end

        % Button pushed function: DisplayButton
        function DisplayButtonPushed(app, event)
            %1-1
            x1=imread('blue.png'); 
            
            %1-2
            x2=imread('blue.png');
            y=rgb2gray(x2);
            
            %1-3
            x3=imread('blue.png');
            y1=rgb2hsv(x3);
            
            %2-1
            x4=imread('blue.png');
            y2=imrotate(x4,45);
            
            %2-2
            x5=imread('blue.png');
            y3=rgb2gray(x5);
            z=imadjust(y3)*5;
            
            %3
            xd=imread('Hello.png');
            for i=1:1:1536 
                for j=1:1:2732 
                    if j>=1 && j<= 342 || j>=683 && j<= 1024 || j>=1365 && j<= 1706 || j>=2047 && j<= 2388  
                        if i>=1 && i<=192 || i>=384 && i<=576 || i>=768 && i<=960 || i>=1152 && i<=1344 
                            if xd (i ,j)==0
                                 xd(i , j , 3)=255;
                                 xd(i , j , 2)=255;
                                 xd(i , j , 1)=255;
                            end
                        end
                    end
                end
            end
            for i=1:1:1536
                for j=1:1:2732 
                    if i>=193 && i<= 385 || i>=577 && i<= 769 || i>=961 && i<=1153 || i>=1345 && i<=1537
                        if j>=342 && j<= 683 || j>=1024 && j<= 1365 || j>=1706 && j<= 2047 || j>=2388 && j<= 2732
                            if xd (i ,j)==0
                                 xd(i , j ,  3)=255;
                                 xd(i , j , 2)=255;
                                 xd(i , j , 1)=255;
                           end
                        end
                    end
                end
            end
            subplot(2,3,1),plotbrowser(x1,'Parent',app.UIAxes);
            %subplot(2,3,2),imshow(y,'Parent',app.UIAxes_2);
            %subplot(2,3,3),imshow(y1,'Parent',app.UIAxes_3);
            %subplot(2,3,4),imshow(y2,'Parent',app.UIAxes_4);
            %subplot(2,3,5),imshow(z,'Parent',app.UIAxes_5);
            %subplot(2,3,6),imshow(xd,'Parent',app.UIAxes_6);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, 'Title')
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_6 = uiaxes(app.UIFigure);
            title(app.UIAxes_6, 'Title')
            xlabel(app.UIAxes_6, 'X')
            ylabel(app.UIAxes_6, 'Y')
            zlabel(app.UIAxes_6, 'Z')
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.DisplayButton = uibutton(app.UIFigure, 'push');
            app.DisplayButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayButtonPushed, true);
            app.DisplayButton.Text = 'Display';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end